classdef Leap_Year_Checker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CekButton                   matlab.ui.control.Button
        LeapYearCheckerLabel        matlab.ui.control.Label
        HasilLabel                  matlab.ui.control.Label
        MasukanTahunEditField       matlab.ui.control.NumericEditField
        MasukanTahunEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CekButton
        function CekButtonPushed(app, event)
            % Ambil nilai tahun dari Edit Field
    tahun = app.MasukanTahunEditField.Value;

    % Logika menggunakan nested if
    if mod(tahun, 4) == 0
        % Ini adalah kandidat tahun kabisat
        if mod(tahun, 100) == 0
            % Ini mungkin bukan tahun kabisat
            if mod(tahun, 400) == 0
                % Tapi ini habis dibagi 400, jadi ini tahun kabisat.
                pesan = "Adalah Tahun Kabisat, karena habis dibagi 400.";
            else
                % Tidak habis dibagi 400, jadi bukan tahun kabisat.
                pesan = "Bukan Tahun Kabisat, karena habis dibagi 100 tapi tidak habis dibagi 400.";
            end
        else
            % Tidak habis dibagi 100, jadi ini tahun kabisat.
            pesan = "Adalah Tahun Kabisat, karena habis dibagi 4 tapi tidak habis dibagi 100.";
        end
    else
        % Tidak habis dibagi 4, jadi sudah pasti bukan tahun kabisat.
        pesan = "Bukan Tahun Kabisat, karena tidak habis dibagi 4.";
    end
    
    % Tampilkan hasil di Label Hasil
    app.HasilLabel.Text = pesan;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MasukanTahunEditFieldLabel = uilabel(app.UIFigure);
            app.MasukanTahunEditFieldLabel.Text = 'Masukan Tahun';
            app.MasukanTahunEditField = uieditfield(app.UIFigure, 'numeric');
            app.HasilLabel = uilabel(app.UIFigure);
            app.HasilLabel.Text = '';
            app.LeapYearCheckerLabel = uilabel(app.UIFigure);
            app.LeapYearCheckerLabel.Text = 'Leap Year Checker';
            app.CekButton = uibutton(app.UIFigure, 'push');
            app.CekButton.ButtonPushedFcn = createCallbackFcn(app, @CekButtonPushed, true);
            app.CekButton.Text = 'Cek';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Leap_Year_Checker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end